classdef ekf_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppliedEstimationLab1UIFigure  matlab.ui.Figure
        SnapshotButton                 matlab.ui.control.Button
        DefaultButton                  matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        Outlier_Label                  matlab.ui.control.Label
        Yellow_Bar                     matlab.ui.control.Label
        IncorrectAssociation_Label     matlab.ui.control.Label
        Black_Bar                      matlab.ui.control.Label
        CorrectAssociation_Label       matlab.ui.control.Label
        Green_Bar                      matlab.ui.control.Label
        Odometry_Label                 matlab.ui.control.Label
        Blue_Box                       matlab.ui.control.Label
        GroundTruth_Label              matlab.ui.control.Label
        Black_Box                      matlab.ui.control.Label
        EstimatedPose_Label            matlab.ui.control.Label
        Green_Box                      matlab.ui.control.Label
        CheckBoxes                     matlab.ui.container.ButtonGroup
        Odometry_CheckBox              matlab.ui.control.CheckBox
        Measurement_CheckBox           matlab.ui.control.CheckBox
        GroundTruth_CheckBox           matlab.ui.control.CheckBox
        outliersdetectedLabel          matlab.ui.control.Label
        OutlierField                   matlab.ui.control.NumericEditField
        incorrectlyclassifiedLabel     matlab.ui.control.Label
        IncorrectField                 matlab.ui.control.NumericEditField
        n_measurementsLabel            matlab.ui.control.Label
        MeasurementField               matlab.ui.control.NumericEditField
        MeasurementsLabel              matlab.ui.control.Label
        OutlierSpinner                 matlab.ui.control.Spinner
        OutlierSwitch                  matlab.ui.control.Switch
        OutlierDetectionLabel          matlab.ui.control.Label
        ErrorLabel                     matlab.ui.control.Label
        Error_theta_Field              matlab.ui.control.NumericEditField
        Error_theta_Label              matlab.ui.control.Label
        Error_y_Field                  matlab.ui.control.NumericEditField
        Error_y_Label                  matlab.ui.control.Label
        Error_x_Field                  matlab.ui.control.NumericEditField
        Error_x_Label                  matlab.ui.control.Label
        PauseButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        EstimatedRobotPoseLabel        matlab.ui.control.Label
        SimulationModeLabel            matlab.ui.control.Label
        thetaField                     matlab.ui.control.NumericEditField
        thetaLabel                     matlab.ui.control.Label
        yField                         matlab.ui.control.NumericEditField
        yLabel                         matlab.ui.control.Label
        xField                         matlab.ui.control.NumericEditField
        xLabel                         matlab.ui.control.Label
        DataAssociationSwitch          matlab.ui.control.Switch
        DataAssociationSwitchLabel     matlab.ui.control.Label
        BatchUpdateSwitch              matlab.ui.control.Switch
        BatchUpdateSwitchLabel         matlab.ui.control.Label
        SensorLabel                    matlab.ui.control.Label
        MotionLabel                    matlab.ui.control.Label
        UncertaintiesLabel             matlab.ui.control.Label
        Spinner_Q1                     matlab.ui.control.Spinner
        rSpinnerLabel                  matlab.ui.control.Label
        Spinner_Q2                     matlab.ui.control.Spinner
        thetaSpinner_2Label            matlab.ui.control.Label
        Spinner_R3                     matlab.ui.control.Spinner
        thetaSpinnerLabel              matlab.ui.control.Label
        Spinner_R12                    matlab.ui.control.Spinner
        xySpinnerLabel                 matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        DatasetSelector                matlab.ui.control.DropDown
        EKFLocalizationLabel           matlab.ui.control.Label
        CloseUpAxis                    matlab.ui.control.UIAxes
        SimulationAxis                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        simulationfile % name of file containing simulation data
        mapfile % name of file containing landmark coordinates
    end
    
    methods (Access = private)
        
        % reset app function after simulation ended or if another dataset
        % is selected
        function reset_app(app)
            % clear all figures
            cla(app.SimulationAxis);
            cla(app.CloseUpAxis);
            title(app.SimulationAxis, 'Simulation Time');
            % enable dataset selection
            app.DatasetSelector.Enable = 'on';
            app.DatasetSelector.Value = 'select_dataset';
            % disable simulation buttons
            app.StartButton.Enable = 'off';
            app.PauseButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            % disable outlier detection
            app.OutlierSwitch.Value = 'Off';
            app.OutlierSpinner.Visible = 'off';
            app.OutlierSwitch.Enable = 'off';
            % disable snapshot button
            app.SnapshotButton.Enable = 'off';
            % set uncertainty spinners to 0 and disable them
            app.Spinner_R12.Value = 0;
            app.Spinner_R3.Value = 0;
            app.Spinner_Q2.Value = 0;
            app.Spinner_Q1.Value = 0;
            app.Spinner_R12.Enable = 'off';
            app.Spinner_R3.Enable = 'off';
            app.Spinner_Q1.Enable = 'off';
            app.Spinner_Q2.Enable = 'off';
            % disable default button
            app.DefaultButton.Enable = 'off';
            % set options switches to off and disable them
            app.BatchUpdateSwitch.Value = 'Off';
            app.DataAssociationSwitch.Value = 'Off';
            app.BatchUpdateSwitch.Enable = 'off';
            app.DataAssociationSwitch.Enable = 'off';
            % set all fields to 0
            app.MeasurementField.Value = 0;
            app.IncorrectField.Value = 0;
            app.OutlierField.Value = 0;
            app.xField.Value = 0;
            app.yField.Value = 0;
            app.thetaField.Value = 0;
            app.Error_x_Field.Value = 0;
            app.Error_y_Field.Value = 0;
            app.Error_theta_Field.Value = 0;
            % set all verbose check boxes to true but disable them
            app.Measurement_CheckBox.Enable = 'off';
            app.GroundTruth_CheckBox.Enable = 'off';
            app.Odometry_CheckBox.Enable = 'off';
            app.Measurement_CheckBox.Value = true;
            app.GroundTruth_CheckBox.Value = true;
            app.Odometry_CheckBox.Value = true;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DatasetSelector
        function DatasetSelectorValueChanged(app, event)
            if strcmp(app.DatasetSelector.Value, 'select_dataset')
                % always reset app if no dataset selected
                reset_app(app);
            else
                % set simulation file to selected dataset
                app.simulationfile = app.DatasetSelector.Value;
                % extract dataset ID from filename
                dataset_id = app.simulationfile(end-4);
                % set mapfile to corresponding file
                app.mapfile = strcat('map', dataset_id, '.txt');
                % initialize GUI for selected dataset
                init_app(app, 'DataSets/', app.mapfile, str2num(dataset_id))
                % enable start button
                app.StartButton.Enable = 'on';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % enable stop, pause and snapshot button, disable start button and
            % dataset selector. Run simulation
            app.PauseButton.Enable = 'on';
            app.StopButton.Enable = 'on';
            app.SnapshotButton.Enable = 'on';
            app.StartButton.Enable = 'off';
            app.DatasetSelector.Enable= 'off';
            % run simulation
            run_simulation(app, 'DataSets/', app.simulationfile)
        end

        % Value changed function: Spinner_R12
        function Spinner_R12ValueChanged(app, event)
            global R
            % update motion model covariance matrix
            R(1,1) = app.Spinner_R12.Value;
            R(1,1)= R(1,1)*R(1,1);
            R(2,2) = R(1,1);
        end

        % Value changed function: Spinner_R3
        function Spinner_R3ValueChanged(app, event)
            global R
            % update motion model covariance matrix
            R(3,3) = app.Spinner_R3.Value / 180 * (pi);
            R(3,3)=R(3,3)*R(3,3);
        end

        % Value changed function: Spinner_Q2
        function Spinner_Q2ValueChanged(app, event)
            global Q
            % update measurement update covariance matrix
            Q(2,2) = app.Spinner_Q2.Value / 180 * (pi);
            Q(2,2)= Q(2,2)*Q(2,2);
        end

        % Value changed function: Spinner_Q1
        function Spinner_Q1ValueChanged(app, event)
            global Q
            % update measurement update covariance matrix
            Q(1,1) = app.Spinner_Q1.Value;  
            Q(1,1)=Q(1,1)*Q(1,1);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % set stop_execution flag so simulation is stopped at the end of the iteration    
            global stop_execution
            stop_execution = 1;
            % reset app for new simulation
            reset_app(app);
            % if simulation finished without being interrupted, rename
            % button from "end" to "stop"
            if strcmp(app.StopButton.Text, 'End')
                app.StopButton.Text = 'Stop';
                app.ErrorLabel.Text = 'Error';
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            % pause execution for 5 seconds    
            pause(5);
        end

        % Value changed function: GroundTruth_CheckBox
        function GroundTruth_CheckBoxValueChanged(app, event)
            % if box is checked ground truth trajectory will be displayed
            global show_ground_truth
            show_ground_truth = app.GroundTruth_CheckBox.Value;
        end

        % Value changed function: Measurement_CheckBox
        function Measurement_CheckBoxValueChanged(app, event)
            % if box is checked measurements will be displayed
            global show_measurements
            show_measurements = app.Measurement_CheckBox.Value;
        end

        % Value changed function: Odometry_CheckBox
        function Odometry_CheckBoxValueChanged(app, event)
            % if box is odometry trajectory will be displayed
            global show_odometry
            show_odometry = app.Odometry_CheckBox.Value;
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            % reset all uncertainty and outlier detection parameters to
            % default values for currently selected dataset
            global default_R
            global default_Q
            global default_lambda_m
            global default_delta_m
            global R
            global Q
            global lambda_m
            global delta_m
            global DATA_ASSOCIATION
            global BATCH_UPDATE  
            DATA_ASSOCIATION = 'On'; 
            BATCH_UPDATE = 'On';
            R = default_R;
            Q = default_Q;
            lambda_m = default_lambda_m;
            delta_m = default_delta_m;
            % display values in Spinners
            app.Spinner_R12.Value = sqrt(R(1, 1));
            app.Spinner_R3.Value = round(sqrt(R(3, 3)) / (pi) * 180);
            app.Spinner_Q1.Value = sqrt(Q(1, 1));
            app.Spinner_Q2.Value = round(sqrt(Q(2, 2)) / (pi) * 180);
            % update outlier detection display
            if lambda_m == 1
                app.OutlierSpinner.Visible = false;
                app.OutlierSwitch.Value = 'Off';
            else
                app.OutlierSpinner.Visible = true;
                app.OutlierSwitch.Value = 'On';
                app.OutlierSpinner.Value = round((1 - delta_m) * 100);
            end
            % enable batch update and data association
            
            app.BatchUpdateSwitch.Value = 'On';
            app.DataAssociationSwitch.Value = 'On';
        end

        % Value changed function: OutlierSwitch
        function OutlierSwitchValueChanged(app, event)
            % enable or disable outlier detection
            global delta_m
            global lambda_m
            global default_delta_m
            global default_lambda_m
            % enabled outlier detection
            if strcmp(app.OutlierSwitch.Value, 'On')
                % set outlier parameters to default values
                app.OutlierSpinner.Visible = true;
                delta_m = default_delta_m;
                lambda_m = default_lambda_m;
                app.OutlierSpinner.Value = round((1 - delta_m) * 100);
            % outlier detection disabled 
            else
                % set spinner to invisible and delta_m to 1 to disable
                % outlier detection
                app.OutlierSpinner.Visible = false;
                delta_m = 1;
                lambda_m = chi2inv(delta_m, 2);
            end
        end

        % Value changed function: OutlierSpinner
        function OutlierSpinnerValueChanged(app, event)
            % update outlier detection parameter
            global delta_m
            global lambda_m
            delta_m = 1 - (app.OutlierSpinner.Value / 100);
            lambda_m = chi2inv(delta_m, 2);   
        end

        % Value changed function: BatchUpdateSwitch
        function BatchUpdateSwitchValueChanged(app, event)
            global BATCH_UPDATE
            BATCH_UPDATE = app.BatchUpdateSwitch.Value;
        end

        % Value changed function: DataAssociationSwitch
        function DataAssociationSwitchValueChanged(app, event)
            global DATA_ASSOCIATION
            DATA_ASSOCIATION = app.DataAssociationSwitch.Value;
        end

        % Button pushed function: SnapshotButton
        function SnapshotButtonPushed(app, event)
            global t 
            global R
            global Q
            global delta_m
            % create a temporary figure with axes.
            snap_fig = figure('Resize', 'off');
            snap_axes = axes(snap_fig);
            % set title
            title(snap_axes, sprintf('Simulation Time: %.1f s', round(t, 1)));
            snap_axes.Title.FontName = app.SimulationAxis.Title.FontName;
            snap_axes.Title.FontSize = app.SimulationAxis.Title.FontSize;
            % copy all SimulationAxis children, take over axes limits and aspect ratio.            
            allChildren = app.SimulationAxis.XAxis.Parent.Children;
            copyobj(allChildren, snap_axes)
            snap_axes.XLim = app.SimulationAxis.XLim;
            snap_axes.YLim = app.SimulationAxis.YLim;
            snap_axes.TickLength = app.SimulationAxis.TickLength;
            snap_axes.FontName = app.SimulationAxis.FontName;
            snap_axes.FontSize = app.SimulationAxis.FontSize;
            snap_axes.Box = 'on';
            snap_axes.XTick = app.SimulationAxis.XTick;
            snap_axes.YTick = app.SimulationAxis.YTick;
            % position axis within figure
            outerpos = snap_axes.OuterPosition;
            top_margin = snap_axes.TightInset(4); 
            ax_height = outerpos(4) - top_margin;
            snap_axes.Position = [outerpos(1) outerpos(2) outerpos(3) ax_height];
            relative_height = snap_axes.Position(4); % relative height of axis w.r.t figure
            % adjust YLim to make space for legend
            y_span_old = snap_axes.YLim(2) - snap_axes.YLim(1);
            relative_y_lim_max_increase = 0.18; % relative increase of y-axis maximum
            snap_axes.YLim(2) = (1+relative_y_lim_max_increase) * snap_axes.YLim(2);
            y_span_new = snap_axes.YLim(2) - snap_axes.YLim(1);
            relative_height_increase = (y_span_new / y_span_old) - 1; % relative increase of axis size
            box_height_axis = relative_height_increase / (1+relative_height_increase); % percentage of axis height free for box
            relative_box_height = box_height_axis*relative_height; % relative height of box w.r.t. figure
            relative_box_width = 0.375;
            box_dim = [(1-relative_box_width)/2, relative_height-relative_box_height, relative_box_width, relative_box_height*0.85];
            % print standard deviations of motion and observation model and
            % outlier detection information
            str1 = strcat('\textbf{Std Motion Model}: x:\thinspace', num2str(round(sqrt(R(1, 1)), 3)), ' y:\thinspace', num2str(round(sqrt(R(2, 2)), 3)), ' $\theta$:\thinspace', num2str(round(sqrt(R(3, 3)), 3)));
            str2 = strcat('\textbf{Std Observation Model:} r:\thinspace', num2str(round(sqrt(Q(1, 1)), 3)), ' $\theta$:\thinspace', num2str(round(sqrt(Q(2, 2)), 3)));
            if delta_m == 1
                str3 = '\textbf{Outlier Detection} Off';
            else
                str3 = strcat('\textbf{Outlier Detection} On, Discard Ratio:\thinspace', num2str(round((1 - delta_m) * 100)), '$\%$');
            end
            str = char(str1, str2, str3);
            % create annotation box with specified information
            box = annotation('textbox', box_dim, 'Interpreter', 'latex', 'String', str);
            % place new figure on top of GUI
            WinOnTop(snap_fig);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AppliedEstimationLab1UIFigure = uifigure('Visible', 'off');
            app.AppliedEstimationLab1UIFigure.Name = 'Applied Estimation | Lab 1';
            app.SimulationAxis = uiaxes(app.AppliedEstimationLab1UIFigure);
            title(app.SimulationAxis, 'Simulation Time')
            app.SimulationAxis.XTick = [];
            app.SimulationAxis.YTick = [];
            app.CloseUpAxis = uiaxes(app.AppliedEstimationLab1UIFigure);
            app.CloseUpAxis.XLim = [-0.5 0.5];
            app.CloseUpAxis.YLim = [-0.5 0.5];
            app.CloseUpAxis.XTick = [];
            app.CloseUpAxis.XTickLabel = '';
            app.CloseUpAxis.YTick = [];
            app.CloseUpAxis.YTickLabel = '';
            app.EKFLocalizationLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.EKFLocalizationLabel.Text = 'EKF Localization';
            app.DatasetSelector = uidropdown(app.AppliedEstimationLab1UIFigure);
            app.DatasetSelector.Items = {'Select Dataset', 'Dataset 1', 'Dataset 2', 'Dataset 3'};
            app.DatasetSelector.ItemsData = {'select_dataset', 'dataset1.txt', 'dataset2.txt', 'dataset3.txt'};
            app.DatasetSelector.ValueChangedFcn = createCallbackFcn(app, @DatasetSelectorValueChanged, true);
            app.DatasetSelector.Value = 'select_dataset';
            app.StartButton = uibutton(app.AppliedEstimationLab1UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.xySpinnerLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.xySpinnerLabel.Text = 'x, y';
            app.Spinner_R12 = uispinner(app.AppliedEstimationLab1UIFigure);
            app.Spinner_R12.Step = 0.01;
            app.Spinner_R12.Limits = [0 10];
            app.Spinner_R12.ValueDisplayFormat = '%.2fm';
            app.Spinner_R12.ValueChangedFcn = createCallbackFcn(app, @Spinner_R12ValueChanged, true);
            app.Spinner_R12.Editable = 'off';
            app.Spinner_R12.Enable = 'off';
            app.thetaSpinnerLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.thetaSpinnerLabel.Text = 'theta';
            app.Spinner_R3 = uispinner(app.AppliedEstimationLab1UIFigure);
            app.Spinner_R3.Limits = [0 360];
            app.Spinner_R3.ValueDisplayFormat = '%d°';
            app.Spinner_R3.ValueChangedFcn = createCallbackFcn(app, @Spinner_R3ValueChanged, true);
            app.Spinner_R3.Editable = 'off';
            app.Spinner_R3.Enable = 'off';
            app.thetaSpinner_2Label = uilabel(app.AppliedEstimationLab1UIFigure);
            app.thetaSpinner_2Label.Text = 'theta';
            app.Spinner_Q2 = uispinner(app.AppliedEstimationLab1UIFigure);
            app.Spinner_Q2.Limits = [0 360];
            app.Spinner_Q2.ValueDisplayFormat = '%d°';
            app.Spinner_Q2.ValueChangedFcn = createCallbackFcn(app, @Spinner_Q2ValueChanged, true);
            app.Spinner_Q2.Editable = 'off';
            app.Spinner_Q2.Enable = 'off';
            app.rSpinnerLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.rSpinnerLabel.Text = 'r';
            app.Spinner_Q1 = uispinner(app.AppliedEstimationLab1UIFigure);
            app.Spinner_Q1.Step = 0.01;
            app.Spinner_Q1.Limits = [0 10];
            app.Spinner_Q1.ValueDisplayFormat = '%.2fm';
            app.Spinner_Q1.ValueChangedFcn = createCallbackFcn(app, @Spinner_Q1ValueChanged, true);
            app.Spinner_Q1.Editable = 'off';
            app.Spinner_Q1.Enable = 'off';
            app.UncertaintiesLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.UncertaintiesLabel.Text = 'Uncertainties';
            app.MotionLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.MotionLabel.Text = 'Motion ';
            app.SensorLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.SensorLabel.Text = 'Sensor';
            app.BatchUpdateSwitchLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.BatchUpdateSwitchLabel.Text = 'Batch Update';
            app.BatchUpdateSwitch = uiswitch(app.AppliedEstimationLab1UIFigure, 'slider');
            app.BatchUpdateSwitch.ValueChangedFcn = createCallbackFcn(app, @BatchUpdateSwitchValueChanged, true);
            app.BatchUpdateSwitch.Enable = 'off';
            app.DataAssociationSwitchLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.DataAssociationSwitchLabel.Text = 'Data Association';
            app.DataAssociationSwitch = uiswitch(app.AppliedEstimationLab1UIFigure, 'slider');
            app.DataAssociationSwitch.ValueChangedFcn = createCallbackFcn(app, @DataAssociationSwitchValueChanged, true);
            app.DataAssociationSwitch.Enable = 'off';
            app.xLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.xLabel.Text = 'x';
            app.xField = uieditfield(app.AppliedEstimationLab1UIFigure, 'numeric');
            app.xField.ValueDisplayFormat = '%.2fm';
            app.xField.Editable = 'off';
            app.yLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.yLabel.Text = 'y';
            app.yField = uieditfield(app.AppliedEstimationLab1UIFigure, 'numeric');
            app.yField.ValueDisplayFormat = '%.2fm';
            app.yField.Editable = 'off';
            app.thetaLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.thetaLabel.Text = 'theta';
            app.thetaField = uieditfield(app.AppliedEstimationLab1UIFigure, 'numeric');
            app.thetaField.ValueDisplayFormat = '%d°';
            app.thetaField.Editable = 'off';
            app.SimulationModeLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.SimulationModeLabel.Text = 'Simulation Mode';
            app.EstimatedRobotPoseLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.EstimatedRobotPoseLabel.Text = 'Estimated Robot Pose';
            app.StopButton = uibutton(app.AppliedEstimationLab1UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.PauseButton = uibutton(app.AppliedEstimationLab1UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause (5s)';
            app.Error_x_Label = uilabel(app.AppliedEstimationLab1UIFigure);
            app.Error_x_Label.Text = 'x';
            app.Error_x_Field = uieditfield(app.AppliedEstimationLab1UIFigure, 'numeric');
            app.Error_x_Field.ValueDisplayFormat = '%.2fm';
            app.Error_x_Field.Editable = 'off';
            app.Error_y_Label = uilabel(app.AppliedEstimationLab1UIFigure);
            app.Error_y_Label.Text = 'y';
            app.Error_y_Field = uieditfield(app.AppliedEstimationLab1UIFigure, 'numeric');
            app.Error_y_Field.ValueDisplayFormat = '%.2fm';
            app.Error_y_Field.Editable = 'off';
            app.Error_theta_Label = uilabel(app.AppliedEstimationLab1UIFigure);
            app.Error_theta_Label.Text = 'theta';
            app.Error_theta_Field = uieditfield(app.AppliedEstimationLab1UIFigure, 'numeric');
            app.Error_theta_Field.ValueDisplayFormat = '%d°';
            app.Error_theta_Field.Editable = 'off';
            app.ErrorLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.ErrorLabel.Text = 'Error';
            app.OutlierDetectionLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.OutlierDetectionLabel.Text = 'Outlier Detection';
            app.OutlierSwitch = uiswitch(app.AppliedEstimationLab1UIFigure, 'slider');
            app.OutlierSwitch.ValueChangedFcn = createCallbackFcn(app, @OutlierSwitchValueChanged, true);
            app.OutlierSwitch.Enable = 'off';
            app.OutlierSpinner = uispinner(app.AppliedEstimationLab1UIFigure);
            app.OutlierSpinner.Limits = [0 100];
            app.OutlierSpinner.ValueDisplayFormat = '%i%%';
            app.OutlierSpinner.ValueChangedFcn = createCallbackFcn(app, @OutlierSpinnerValueChanged, true);
            app.OutlierSpinner.Editable = 'off';
            app.OutlierSpinner.Visible = 'off';
            app.MeasurementsLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.MeasurementsLabel.Text = 'Measurements';
            app.MeasurementField = uieditfield(app.AppliedEstimationLab1UIFigure, 'numeric');
            app.MeasurementField.ValueDisplayFormat = '%d';
            app.MeasurementField.Editable = 'off';
            app.n_measurementsLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.n_measurementsLabel.Text = 'number of measurements';
            app.IncorrectField = uieditfield(app.AppliedEstimationLab1UIFigure, 'numeric');
            app.IncorrectField.ValueDisplayFormat = '%d';
            app.IncorrectField.Editable = 'off';
            app.incorrectlyclassifiedLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.incorrectlyclassifiedLabel.Text = 'incorrectly classified';
            app.OutlierField = uieditfield(app.AppliedEstimationLab1UIFigure, 'numeric');
            app.OutlierField.ValueDisplayFormat = '%d';
            app.OutlierField.Editable = 'off';
            app.outliersdetectedLabel = uilabel(app.AppliedEstimationLab1UIFigure);
            app.outliersdetectedLabel.Text = 'outliers detected';
            app.CheckBoxes = uibuttongroup(app.AppliedEstimationLab1UIFigure);
            app.GroundTruth_CheckBox = uicheckbox(app.CheckBoxes);
            app.GroundTruth_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GroundTruth_CheckBoxValueChanged, true);
            app.GroundTruth_CheckBox.Enable = 'off';
            app.GroundTruth_CheckBox.Text = 'show ground truth';
            app.GroundTruth_CheckBox.Value = true;
            app.Measurement_CheckBox = uicheckbox(app.CheckBoxes);
            app.Measurement_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Measurement_CheckBoxValueChanged, true);
            app.Measurement_CheckBox.Enable = 'off';
            app.Measurement_CheckBox.Text = 'show measurements';
            app.Measurement_CheckBox.Value = true;
            app.Odometry_CheckBox = uicheckbox(app.CheckBoxes);
            app.Odometry_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Odometry_CheckBoxValueChanged, true);
            app.Odometry_CheckBox.Enable = 'off';
            app.Odometry_CheckBox.Text = 'show odometry';
            app.Odometry_CheckBox.Value = true;
            app.Panel = uipanel(app.AppliedEstimationLab1UIFigure);
            app.Green_Box = uilabel(app.Panel);
            app.Green_Box.Text = '';
            app.EstimatedPose_Label = uilabel(app.Panel);
            app.EstimatedPose_Label.Text = 'estimated pose';
            app.Black_Box = uilabel(app.Panel);
            app.Black_Box.Text = '';
            app.GroundTruth_Label = uilabel(app.Panel);
            app.GroundTruth_Label.Text = 'ground truth pose';
            app.Blue_Box = uilabel(app.Panel);
            app.Blue_Box.Text = '';
            app.Odometry_Label = uilabel(app.Panel);
            app.Odometry_Label.Text = 'odometry pose';
            app.Green_Bar = uilabel(app.Panel);
            app.Green_Bar.Text = '';
            app.CorrectAssociation_Label = uilabel(app.Panel);
            app.CorrectAssociation_Label.Text = 'correct association';
            app.Black_Bar = uilabel(app.Panel);
            app.Black_Bar.Text = '';
            app.IncorrectAssociation_Label = uilabel(app.Panel);
            app.IncorrectAssociation_Label.Text = 'incorrect association';
            app.Yellow_Bar = uilabel(app.Panel);
            app.Yellow_Bar.Text = '';
            app.Outlier_Label = uilabel(app.Panel);
            app.Outlier_Label.Text = 'outlier';
            app.DefaultButton = uibutton(app.AppliedEstimationLab1UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Enable = 'off';
            app.DefaultButton.Text = 'set to default';
            app.SnapshotButton = uibutton(app.AppliedEstimationLab1UIFigure, 'push');
            app.SnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SnapshotButtonPushed, true);
            app.SnapshotButton.Icon = 'camera_icon.png';
            app.SnapshotButton.Enable = 'off';
            app.SnapshotButton.Text = '';
            % Show the figure after all components are created
            app.AppliedEstimationLab1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ekf_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AppliedEstimationLab1UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppliedEstimationLab1UIFigure)
        end
    end
end